classdef Measures_Part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartmeasureButton     matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        AnalyzerPanel          matlab.ui.container.Panel
        IPEditField_2          matlab.ui.control.NumericEditField
        IPEditField_3          matlab.ui.control.NumericEditField
        IPEditField_4          matlab.ui.control.NumericEditField
        AFStopEditField        matlab.ui.control.NumericEditField
        startstopLabel         matlab.ui.control.Label
        IPEditFieldLabel       matlab.ui.control.Label
        IPEditField_1          matlab.ui.control.NumericEditField
        FMHzEditFieldLabel     matlab.ui.control.Label
        AFStartEditField       matlab.ui.control.NumericEditField
        BWkHzLabel             matlab.ui.control.Label
        BWEditField            matlab.ui.control.NumericEditField
        PointNumLabel          matlab.ui.control.Label
        PointsNumEditField     matlab.ui.control.NumericEditField
        ATTdBLabel             matlab.ui.control.Label
        AttEditField           matlab.ui.control.NumericEditField
        PreAmpCheckBox         matlab.ui.control.CheckBox
        ButtonGroup            matlab.ui.container.ButtonGroup
        AverageButton          matlab.ui.control.RadioButton
        MaxHoldButton          matlab.ui.control.RadioButton
        CWButton               matlab.ui.control.RadioButton
        RepeatsEditFieldLabel  matlab.ui.control.Label
        RepeatsEditField       matlab.ui.control.NumericEditField
        RPUPanel               matlab.ui.container.Panel
        ScanButton             matlab.ui.control.Button
        COMDropDownLabel       matlab.ui.control.Label
        COMDropDown            matlab.ui.control.DropDown
        RFStopEditField        matlab.ui.control.NumericEditField
        startstopLabel_2       matlab.ui.control.Label
        FMHzEditFieldLabel_2   matlab.ui.control.Label
        RFStartEditField       matlab.ui.control.NumericEditField
        LoadButton             matlab.ui.control.Button
        ButtonLeft             matlab.ui.control.Button
        ButtonRight            matlab.ui.control.Button
        GeneratorPanel         matlab.ui.container.Panel
        IPEditField_6          matlab.ui.control.NumericEditField
        IPEditField_7          matlab.ui.control.NumericEditField
        IPEditField_8          matlab.ui.control.NumericEditField
        IPEditField_8Label     matlab.ui.control.Label
        IPEditField_5          matlab.ui.control.NumericEditField
        FMHzLabel              matlab.ui.control.Label
        genDFEditField         matlab.ui.control.NumericEditField
        PdBmLabel              matlab.ui.control.Label
        PEditField             matlab.ui.control.NumericEditField
        genDFStopEditField     matlab.ui.control.NumericEditField
        genDFStartEditField    matlab.ui.control.NumericEditField
        startstepstopLabel     matlab.ui.control.Label
        ButtonLeft_2           matlab.ui.control.Button
        ButtonRight_2          matlab.ui.control.Button
        FrpuLabel              matlab.ui.control.Label
        FgenLabel              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function AnalyzerSettings(~, analyzerVisa, freqStart, freqStop, pointsNum, sweepCount, RBW, VBW, preAmp, att, traceMode)
            fprintf(analyzerVisa,'INIT:CONT OFF');
            
            fprintf(analyzerVisa,['FREQ:START ' num2str(freqStart) 'MHz']); % start
            fprintf(analyzerVisa,['FREQ:STOP ' num2str(freqStop) 'MHz']); % stop
            
            fprintf(analyzerVisa,['SENS:SWE:POIN ' num2str(pointsNum)]); %число точек
            
            switch traceMode
                case 0
                    fprintf(analyzerVisa,'DISP:TRAC1:MODE WRITE');
                    fprintf(analyzerVisa, 'SENS:SWEEP:COUNT 1');
                case 1
                    fprintf(analyzerVisa,'DISP:TRAC1:MODE AVER');
                    fprintf(analyzerVisa, ['AVERage:COUN ' num2str(sweepCount)]); %усреднение
                case 2
                    fprintf(analyzerVisa,'DISP:TRAC1:MODE MAXH');
                    fprintf(analyzerVisa, ['SENS:SWEEP:COUNT ' num2str(sweepCount)]);
            end


            fprintf(analyzerVisa, 'DISP:TRAC1:Y:RLEV -50 dBm'); %чтоб было видно на экране
            
            fprintf(analyzerVisa, 'INP:ATT:AUTO OFF');
            fprintf(analyzerVisa, ['INP:ATT ' num2str(att) 'dB']); %аттенюатор
            fprintf(analyzerVisa,['INP:GAIN:STAT ' preAmp]); %вкл/выкл предусилителя
            
            fprintf(analyzerVisa,'BAND:AUTO OFF');
            fprintf(analyzerVisa, ['BAND ' num2str(RBW) ' KHZ']); %RBW
            
            fprintf(analyzerVisa,'BAND:VID:AUTO ON');
            %fprintf(analyzerVisa, ['BAND:VID ' num2str(VBW) ' KHZ']); %VBW
        end
        
        function spectrum = GetTrace(~, analyzerVisa)
            fprintf(analyzerVisa,'INIT;*WAI');
            fprintf(analyzerVisa,'TRAC1:DATA? TRACE1');
            spectrum=str2num(fscanf(analyzerVisa));
        end
        
        function SetCenterFreq(~, centerFreq)
            fprintf(analyzerVisa,['FREQ:CENT ' num2str(centerFreq*1e6)]);
        end
        
        function SetFreqRange(~, freqStart, freqStop)
            fprintf(analyzerVisa,['FREQ:START ' num2str(freqStart) 'MHz']); % start
            fprintf(analyzerVisa,['FREQ:STOP ' num2str(freqStop) 'MHz']); % stop
        end
        
        function frequencies = GetFrequencies(~, analyzerVisa)
            fprintf(analyzerVisa,'TRAC1:X? TRACE1');
            frequencies=str2num(fscanf(analyzerVisa));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartmeasureButton
        function StartmeasureButtonPushed(app, event)
            
            clc;
            global measData;
            global rpu;
            global analyzer;
            global generator;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            measData.Freqs = [];
            measData.Spectrums = [];
            
            %подключение к анализатору
            analyzer.IP = [num2str(app.IPEditField_1.Value) '.' num2str(app.IPEditField_2.Value) '.' num2str(app.IPEditField_3.Value) '.' num2str(app.IPEditField_4.Value)];
            analyzerVisa = visa('ni', ['TCPIP::' analyzer.IP],'Timeout',100);
            set(analyzerVisa,'InputBufferSize',60000);
            fopen(analyzerVisa);
            
            %настройки анализатора
            analyzer.FreqStart = app.AFStartEditField.Value;
            analyzer.FreqStop = app.AFStopEditField.Value;
            analyzer.PointsNum = app.PointsNumEditField.Value;
            freqStep = (analyzer.FreqStop - analyzer.FreqStart)/(analyzer.PointsNum - 1);
            analyzer.Freqs = analyzer.FreqStart:freqStep:analyzer.FreqStop;
            analyzer.RBW = app.BWEditField.Value;
            analyzer.VBW = app.BWEditField.Value;
            analyzer.SweepCount=app.RepeatsEditField.Value;
            analyzer.Att = app.AttEditField.Value;
            if (app.PreAmpCheckBox.Value)
                analyzer.PreAmpEnabled='ON';
            else
                analyzer.PreAmpEnabled='OFF';
            end
            traceMode = 0;
            if app.CWButton.Value
                analyzer.TraceMode = 'Clear/Write';
                traceMode = 0;
                analyzer.SweepCount = 1;
            end
            if app.AverageButton.Value
                analyzer.TraceMode = 'Average';
                traceMode = 1;
            end
            if app.MaxHoldButton.Value
                analyzer.TraceMode = 'MaxHold';
                traceMode = 2;
            end
            AnalyzerSettings(app, analyzerVisa, ...
                analyzer.FreqStart, ...
                analyzer.FreqStop, ...
                analyzer.PointsNum, ...
                analyzer.SweepCount, ...
                analyzer.RBW, ...
                analyzer.VBW, ...
                analyzer.PreAmpEnabled, ...
                analyzer.Att, ...
                traceMode);
            
            %подключение к РПУ
            rpuSerial = serialport(app.COMDropDown.Value, 115200);
            rpuSerial.DataBits = 8;
            rpuSerial.Parity = 'Even';
            rpuSerial.StopBits = 1;

            %настройки РПУ
            rpu.FreqStart = app.RFStartEditField.Value;
            rpu.FreqStop = app.RFStopEditField.Value;
            rpu.Freqs = rpu.FreqStart : 250 : rpu.FreqStop;
            rpu.FreqStartNum = ((rpu.FreqStart / 250 ) - 3);
            rpu.FreqStopNum = ((rpu.FreqStop / 250 ) - 3);
            
            %подключение к генератору
            generator.IP = [num2str(app.IPEditField_5.Value) '.' num2str(app.IPEditField_6.Value) '.' num2str(app.IPEditField_7.Value) '.' num2str(app.IPEditField_8.Value)];
            generatorVisa = visa('ni', ['TCPIP::' generator.IP],'Timeout',100);
            set(generatorVisa,'InputBufferSize',60000);
            fopen(generatorVisa);
            
            %настройки генератора
            generator.Power = app.PEditField.Value;
            generator.FreqStep = app.genDFEditField.Value;
            generator.FreqDeltaStart = app.genDFStartEditField.Value;
            generator.FreqDeltaStop = app.genDFStopEditField.Value;
            generator.DeltaFreqs = generator.FreqDeltaStart:generator.FreqStep:generator.FreqDeltaStop;
            fprintf(generatorVisa,['SOUR:POW:POW ' num2str(generator.Power)]); % level
            fprintf(generatorVisa,['SOUR:FREQ:FREQ ' num2str(rpu.FreqStart-generator.FreqDeltaStart) 'MHz']);
            
            measData.Freqs = GetFrequencies(app, analyzerVisa);
            i=0;
            %измерения
            fprintf(generatorVisa,'OUTP ON'); % RF on
            for rpuFreqNum=rpu.FreqStartNum:rpu.FreqStopNum
                i=i+1;
                j=0;
                freqToSend = [2, rpuFreqNum];
                write(rpuSerial, freqToSend, 'uint8');
                for genFreqDelta = generator.FreqDeltaStart:generator.FreqStep:generator.FreqDeltaStop
                    j=j+1;
                    fprintf(generatorVisa,['SOUR:FREQ:FREQ ' num2str(rpu.Freqs(i) + genFreqDelta) 'MHz']);
                    pause(0.5);
                    measData.Spectrums(i, j, :) = GetTrace(app, analyzerVisa);
                    %  Spectrums (номер частоты РПУ, номер частоты генератора, номер частоты анализатора)
                    plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(i, j, :)));
                    if genFreqDelta<0
                        axesTitle = [num2str(rpu.Freqs(i)) ' - ' num2str(abs(genFreqDelta))];
                    else
                        axesTitle = [num2str(rpu.Freqs(i)) ' + ' num2str(genFreqDelta)];
                    end
                    app.UIAxes.Title.String = axesTitle;
                    drawnow();
                end
            end
            indexRpuFreqNum = i;
            indexGenFreqNum = j;
            
            %закрываем, сохраняем 
            fprintf(generatorVisa,'OUTP OFF'); % RF off
            fprintf(analyzerVisa,'INIT:CONT ON');
            
            fclose(analyzerVisa);
            fclose(generatorVisa);
            delete(rpuSerial);

            uisave({'measData', 'analyzer', 'rpu', 'generator'},'measures_part2.mat');
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.COMDropDown.Items = serialportlist("available");
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global measData;
            global rpu;
            global analyzer;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            toLoad=uigetfile({'.mat'}, 'LoadFile');
            if size(toLoad,2)==1
                return;
            else
                indexRpuFreqNum = 1;
                indexGenFreqNum = 1;
                load(toLoad, 'measData', 'rpu', 'analyzer', 'generator');
                plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(1, 1, :)));
                app.UIAxes.Title.String = [num2str(rpu.Freqs(1)) '-' num2str(abs(generator.DeltaFreqs(1)))];
            end
        end

        % Button pushed function: ButtonLeft
        function ButtonLeftPushed(app, event)
            global measData;
            global rpu;
            global analyzer;
            global generator;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            if indexRpuFreqNum > 1
                indexRpuFreqNum = indexRpuFreqNum - 1;
                plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(indexRpuFreqNum, indexGenFreqNum, :)));
                if generator.DeltaFreqs(indexGenFreqNum)<0
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' - ' num2str(abs(generator.DeltaFreqs(indexGenFreqNum)))];
                else
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' + ' num2str(generator.DeltaFreqs(indexGenFreqNum))];
                end
                app.UIAxes.Title.String = axesTitle;
            end
        end

        % Button pushed function: ButtonRight
        function ButtonRightPushed(app, event)
            global measData;
            global rpu;
            global analyzer;
            global generator;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            if indexRpuFreqNum < size(rpu.Freqs, 2)
                indexRpuFreqNum = indexRpuFreqNum + 1;
                plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(indexRpuFreqNum, indexGenFreqNum, :)));
                if generator.DeltaFreqs(indexGenFreqNum)<0
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' - ' num2str(abs(generator.DeltaFreqs(indexGenFreqNum)))];
                else
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' + ' num2str(generator.DeltaFreqs(indexGenFreqNum))];
                end
                app.UIAxes.Title.String = axesTitle;
            end
        end

        % Button pushed function: ButtonLeft_2
        function ButtonLeft_2Pushed(app, event)
            global measData;
            global rpu;
            global analyzer;
            global generator;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            if indexGenFreqNum > 1
                indexGenFreqNum = indexGenFreqNum - 1;
                plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(indexRpuFreqNum, indexGenFreqNum, :)));
                if generator.DeltaFreqs(indexGenFreqNum)<0
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' - ' num2str(abs(generator.DeltaFreqs(indexGenFreqNum)))];
                else
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' + ' num2str(generator.DeltaFreqs(indexGenFreqNum))];
                end
                app.UIAxes.Title.String = axesTitle;
            end
        end

        % Button pushed function: ButtonRight_2
        function ButtonRight_2Pushed(app, event)
            global measData;
            global rpu;
            global analyzer;
            global generator;
            global indexRpuFreqNum;
            global indexGenFreqNum;
            if indexGenFreqNum < size(generator.DeltaFreqs, 2)
                indexGenFreqNum = indexGenFreqNum + 1;
                plot(app.UIAxes, measData.Freqs, squeeze(measData.Spectrums(indexRpuFreqNum, indexGenFreqNum, :)));
                if generator.DeltaFreqs(indexGenFreqNum)<0
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' - ' num2str(abs(generator.DeltaFreqs(indexGenFreqNum)))];
                else
                    axesTitle = [num2str(rpu.Freqs(indexRpuFreqNum)) ' + ' num2str(generator.DeltaFreqs(indexGenFreqNum))];
                end
                app.UIAxes.Title.String = axesTitle;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartmeasureButton = uibutton(app.UIFigure, 'push');
            app.StartmeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StartmeasureButtonPushed, true);
            app.StartmeasureButton.Text = 'Start measure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'title')
            xlabel(app.UIAxes, 'f [Hz]')
            ylabel(app.UIAxes, 'W [dBm]')
            app.AnalyzerPanel = uipanel(app.UIFigure);
            app.AnalyzerPanel.Title = 'Analyzer';
            app.IPEditField_2 = uieditfield(app.AnalyzerPanel, 'numeric');
            app.IPEditField_2.ValueDisplayFormat = '%.0f';
            app.IPEditField_2.Value = 168;
            app.IPEditField_3 = uieditfield(app.AnalyzerPanel, 'numeric');
            app.IPEditField_3.ValueDisplayFormat = '%.0f';
            app.IPEditField_3.Value = 1;
            app.IPEditField_4 = uieditfield(app.AnalyzerPanel, 'numeric');
            app.IPEditField_4.ValueDisplayFormat = '%.0f';
            app.IPEditField_4.Value = 97;
            app.AFStopEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.AFStopEditField.ValueDisplayFormat = '%.0f';
            app.AFStopEditField.Value = 2020;
            app.startstopLabel = uilabel(app.AnalyzerPanel);
            app.startstopLabel.Text = '   start                    stop';
            app.IPEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField_1 = uieditfield(app.AnalyzerPanel, 'numeric');
            app.IPEditField_1.ValueDisplayFormat = '%.0f';
            app.IPEditField_1.Value = 192;
            app.FMHzEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.FMHzEditFieldLabel.Text = 'F [MHz]';
            app.AFStartEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.AFStartEditField.ValueDisplayFormat = '%.0f';
            app.AFStartEditField.Value = 1480;
            app.BWkHzLabel = uilabel(app.AnalyzerPanel);
            app.BWkHzLabel.Text = 'BW [kHz]';
            app.BWEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.BWEditField.ValueDisplayFormat = '%.0f';
            app.BWEditField.Value = 100;
            app.PointNumLabel = uilabel(app.AnalyzerPanel);
            app.PointNumLabel.Text = 'PointNum';
            app.PointsNumEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.PointsNumEditField.ValueDisplayFormat = '%.0f';
            app.PointsNumEditField.Value = 541;
            app.ATTdBLabel = uilabel(app.AnalyzerPanel);
            app.ATTdBLabel.Text = 'ATT [dB]';
            app.AttEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.AttEditField.ValueDisplayFormat = '%.0f';
            app.PreAmpCheckBox = uicheckbox(app.AnalyzerPanel);
            app.PreAmpCheckBox.Text = 'PreAmp';
            app.ButtonGroup = uibuttongroup(app.AnalyzerPanel);
            app.AverageButton = uiradiobutton(app.ButtonGroup);
            app.AverageButton.Text = 'Average';
            app.MaxHoldButton = uiradiobutton(app.ButtonGroup);
            app.MaxHoldButton.Text = 'MaxHold';
            app.CWButton = uiradiobutton(app.ButtonGroup);
            app.CWButton.Text = 'C/W';
            app.CWButton.Value = true;
            app.RepeatsEditFieldLabel = uilabel(app.AnalyzerPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.RepeatsEditField = uieditfield(app.AnalyzerPanel, 'numeric');
            app.RepeatsEditField.ValueDisplayFormat = '%.0f';
            app.RepeatsEditField.Value = 10;
            app.RPUPanel = uipanel(app.UIFigure);
            app.RPUPanel.Title = 'RPU';
            app.ScanButton = uibutton(app.RPUPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.COMDropDownLabel = uilabel(app.RPUPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.RPUPanel);
            app.COMDropDown.Items = {};
            app.COMDropDown.Value = {};
            app.RFStopEditField = uieditfield(app.RPUPanel, 'numeric');
            app.RFStopEditField.ValueDisplayFormat = '%.0f';
            app.RFStopEditField.Value = 2500;
            app.startstopLabel_2 = uilabel(app.RPUPanel);
            app.startstopLabel_2.Text = '   start                    stop';
            app.FMHzEditFieldLabel_2 = uilabel(app.RPUPanel);
            app.FMHzEditFieldLabel_2.Text = 'F [MHz]';
            app.RFStartEditField = uieditfield(app.RPUPanel, 'numeric');
            app.RFStartEditField.ValueDisplayFormat = '%.0f';
            app.RFStartEditField.Value = 2000;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ButtonLeft = uibutton(app.UIFigure, 'push');
            app.ButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonLeftPushed, true);
            app.ButtonLeft.Text = '<<';
            app.ButtonRight = uibutton(app.UIFigure, 'push');
            app.ButtonRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonRightPushed, true);
            app.ButtonRight.Text = '>>';
            app.GeneratorPanel = uipanel(app.UIFigure);
            app.GeneratorPanel.Title = 'Generator';
            app.IPEditField_6 = uieditfield(app.GeneratorPanel, 'numeric');
            app.IPEditField_6.ValueDisplayFormat = '%.0f';
            app.IPEditField_6.Value = 168;
            app.IPEditField_7 = uieditfield(app.GeneratorPanel, 'numeric');
            app.IPEditField_7.ValueDisplayFormat = '%.0f';
            app.IPEditField_7.Value = 1;
            app.IPEditField_8 = uieditfield(app.GeneratorPanel, 'numeric');
            app.IPEditField_8.ValueDisplayFormat = '%.0f';
            app.IPEditField_8.Value = 95;
            app.IPEditField_8Label = uilabel(app.GeneratorPanel);
            app.IPEditField_8Label.Text = 'IP';
            app.IPEditField_5 = uieditfield(app.GeneratorPanel, 'numeric');
            app.IPEditField_5.ValueDisplayFormat = '%.0f';
            app.IPEditField_5.Value = 192;
            app.FMHzLabel = uilabel(app.GeneratorPanel);
            app.FMHzLabel.Text = 'F [MHz]';
            app.genDFEditField = uieditfield(app.GeneratorPanel, 'numeric');
            app.genDFEditField.ValueDisplayFormat = '%.0f';
            app.genDFEditField.Value = 10;
            app.PdBmLabel = uilabel(app.GeneratorPanel);
            app.PdBmLabel.Text = 'P [dBm]';
            app.PEditField = uieditfield(app.GeneratorPanel, 'numeric');
            app.PEditField.ValueDisplayFormat = '%.0f';
            app.PEditField.Value = -40;
            app.genDFStopEditField = uieditfield(app.GeneratorPanel, 'numeric');
            app.genDFStopEditField.ValueDisplayFormat = '%.0f';
            app.genDFStopEditField.Value = 250;
            app.genDFStartEditField = uieditfield(app.GeneratorPanel, 'numeric');
            app.genDFStartEditField.ValueDisplayFormat = '%.0f';
            app.genDFStartEditField.Value = -250;
            app.startstepstopLabel = uilabel(app.GeneratorPanel);
            app.startstepstopLabel.Text = '   start        step            stop';
            app.ButtonLeft_2 = uibutton(app.UIFigure, 'push');
            app.ButtonLeft_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonLeft_2Pushed, true);
            app.ButtonLeft_2.Text = '<<';
            app.ButtonRight_2 = uibutton(app.UIFigure, 'push');
            app.ButtonRight_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRight_2Pushed, true);
            app.ButtonRight_2.Text = '>>';
            app.FrpuLabel = uilabel(app.UIFigure);
            app.FrpuLabel.Text = 'Frpu';
            app.FgenLabel = uilabel(app.UIFigure);
            app.FgenLabel.Text = 'Fgen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Measures_Part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end